classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        ConvolutionTab                 matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        SelectimagefileLabel           matlab.ui.control.Label
        SelectthefilehereEditField_Conv  matlab.ui.control.EditField
        ChooseFileButton               matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        MeanFilteringTab               matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        ChooseFileButton_MeanFilter    matlab.ui.control.Button
        SelectimagefileEditFieldLabel  matlab.ui.control.Label
        SelectimagefileEditField       matlab.ui.control.EditField
        FrequencySmoothingTab          matlab.ui.container.Tab
        ChooseFilterDropDownLabel      matlab.ui.control.Label
        ChooseFilterDropDown           matlab.ui.control.DropDown
        FilteringwithLowPassandHighPassFiltersLabel  matlab.ui.control.Label
        ChooseFileButton_MeanFilter_2  matlab.ui.control.Button
        SelectthefilehereEditField_2Label_2  matlab.ui.control.Label
        SelectthefilehereEditField_Conv_2  matlab.ui.control.EditField
        ApplyButton                    matlab.ui.control.Button
        UIAxesFreqSmooth               matlab.ui.control.UIAxes
        EnterD0valueLabel              matlab.ui.control.Label
        D0                             matlab.ui.control.NumericEditField
        HighBoostingTab                matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        SourceImageButton              matlab.ui.control.Button
        HighBoostButton                matlab.ui.control.Button
        SourceEditField                matlab.ui.control.EditField
        SourceImage                    matlab.ui.control.Image
        HighBoostImage                 matlab.ui.control.Image
        HighBoostEditField             matlab.ui.control.NumericEditField
        LowPassImage                   matlab.ui.control.Image
        MedianFilteringTab             matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        ClearImageButton               matlab.ui.control.Button
        ClearEditField                 matlab.ui.control.EditField
        ClearImage                     matlab.ui.control.Image
        NoiseImage                     matlab.ui.control.Image
        MedianImage                    matlab.ui.control.Image
        NoiseEditField                 matlab.ui.control.EditField
        SaltPepperEditField            matlab.ui.control.NumericEditField
        NoiseImageButton               matlab.ui.control.Button
        SaltPepperButton               matlab.ui.control.Button
        ResultImageButton              matlab.ui.control.Button
        Tab                            matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mask_input)
            
        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.SelectthefilehereEditField_Conv.Value = fullpath;
                im = imread(fullpath);
                
                mask = [0 -1 0;
                          -1 5 -1;
                          0 -1 0];
                mask = mask/sum(sum(mask));
                
                [image_result, matlab_result] = convolution2D(im, mask);
                imshow(image_result,'Parent',app.UIAxes)
            end
        end

        % Callback function
        function ChooseImageButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.FileEditField_2.Value = fullpath;
                im = imread(fullpath);
                app.EnhancementInImage.ImageSource = im;
                [x, bins] = histog(im);
                bar(app.EnhancementInHist, x, bins);
            end
        end

        % Callback function
        function ApplyButtonPushed(app, event)
            im = imread(app.FileEditField_2.Value);
            im = brighten(im, app.aSlider.Value, app.bSlider.Value);
            [x, bins] = histog(im);
            bar(app.EnhancementOutHist, x, bins);
            app.EnhancementOutImage.ImageSource = im;
        end

        % Callback function
        function ApplyButton_2Pushed(app, event)
            im = imread(app.FileEditField_2.Value);
            im = logtran(im, app.cSlider.Value);
            [x, bins] = histog(im);
            bar(app.EnhancementOutHist, x, bins);
            app.EnhancementOutImage.ImageSource = im;
        end

        % Callback function
        function ApplyButton_3Pushed(app, event)
            im = imread(app.FileEditField_2.Value);
            im = exp(im, app.cSlider.Value);
            [x, bins] = histog(im);
            bar(app.EnhancementOutHist, x, bins);
            app.EnhancementOutImage.ImageSource = im;
        end

        % Callback function
        function bSliderValueChanged(app, event)
            value = app.bSlider.Value;
            app.bEditField.Value = value;
        end

        % Callback function
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            app.bSlider.Value = value;
        end

        % Callback function
        function aSliderValueChanged(app, event)
            value = app.aSlider.Value;
            app.aSlider.Value = value;
        end

        % Callback function
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            app.aSlider.Value = value;
        end

        % Callback function
        function ChooseImageButton_2Pushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.FileEditField_3.Value = fullpath;
                im = imread(fullpath);
                app.EqualizationInImage.ImageSource = im;
                [x, bins] = histog(im);
                bar(app.EqualizationInHist, x, bins);

                im_equalized = histog_equalization(im);
                app.EqualizationOutImage.ImageSource = im_equalized;
                [x, bins] = histog(im_equalized);
                bar(app.EqualizationOutHist, x, bins);
            end
        end

        % Button pushed function: SourceImageButton
        function SourceImageButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.SourceEditField.Value = fullpath;
                im = imread(fullpath);
                im = gray_to_rgb(im);
                app.SourceImage.ImageSource = im;
                app.LowPassImage.ImageSource = uint8(low_pass(im));
            end
        end

        % Button pushed function: HighBoostButton
        function HighBoostButtonPushed(app, event)
            im = imread(app.SourceEditField.Value);
            im = gray_to_rgb(im);
            app.HighBoostImage.ImageSource = high_boost(im, app.HighBoostEditField.Value);
        end

        % Button pushed function: ClearImageButton
        function ClearImageButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.ClearEditField.Value = fullpath;
                im = imread(fullpath);
                app.ClearImage.ImageSource = gray_to_rgb(im);
            end
        end

        % Button pushed function: NoiseImageButton
        function NoiseImageButtonPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.NoiseEditField.Value = fullpath;
                im = imread(fullpath);
                app.NoiseImage.ImageSource = gray_to_rgb(im);
            end
        end

        % Button pushed function: SaltPepperButton
        function SaltPepperButtonPushed(app, event)
            im = app.ClearImage.ImageSource;
            app.NoiseImage.ImageSource = salt_pepper_noise(im, app.SaltPepperEditField.Value);
        end

        % Button pushed function: ResultImageButton
        function ResultImageButtonPushed(app, event)
            im = app.NoiseImage.ImageSource;
            app.MedianImage.ImageSource = median_filter(im);
        end

        % Button pushed function: ChooseFileButton_MeanFilter
        function ChooseFileButtonPushed_MeanFilter(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.SelectthefilehereEditField_Conv.Value = fullpath;
                im = imread(fullpath);
                I = im2double(im);
                
                % Mean filter
                mean_filter = [1 2 1;
                    2 4 2;
                    1 2 1];
                [n, ~] = size(mean_filter);
                mean_filter = mean_filter/n;
                
                [image_result, ~] = convolution2D(im, mean_filter);
                imshow(image_result,'Parent',app.UIAxes2);
            end
        end

        % Button pushed function: ChooseFileButton_MeanFilter_2
        function ChooseFileFreqSmooth(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
            else
                fullpath = fullfile(path, file);
                app.ClearEditField.Value = fullpath;
                im = imread(fullpath);
                
                filter_type = string(app.ChooseFilterDropDown.Value);
                D0 = app.D0.Value;
                
                image_result = freq_imsmoothing(im, filter_type, D0);
                imshow(image_result,'Parent',app.UIAxesFreqSmooth);
            end
        end

        % Button pushed function: ApplyButton
        function ApplyFreqSmoothButton(app, event)
            
        end

        % Value changed function: D0
        function D0ValueChanged(app, event)
            value = app.D0.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.GridLayout2 = uigridlayout(app.ConvolutionTab);
            app.SelectimagefileLabel = uilabel(app.GridLayout2);
            app.SelectimagefileLabel.Text = 'Select image file:';
            app.SelectthefilehereEditField_Conv = uieditfield(app.GridLayout2, 'text');
            app.ChooseFileButton = uibutton(app.GridLayout2, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Convolution result')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.MeanFilteringTab = uitab(app.TabGroup);
            app.MeanFilteringTab.Title = 'Mean Filtering';
            app.UIAxes2 = uiaxes(app.MeanFilteringTab);
            title(app.UIAxes2, 'Image Smoothing using Mean Filter result')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.ChooseFileButton_MeanFilter = uibutton(app.MeanFilteringTab, 'push');
            app.ChooseFileButton_MeanFilter.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed_MeanFilter, true);
            app.ChooseFileButton_MeanFilter.Text = 'Choose File';
            app.SelectimagefileEditFieldLabel = uilabel(app.MeanFilteringTab);
            app.SelectimagefileEditFieldLabel.Text = 'Select image file: ';
            app.SelectimagefileEditField = uieditfield(app.MeanFilteringTab, 'text');
            app.FrequencySmoothingTab = uitab(app.TabGroup);
            app.FrequencySmoothingTab.Title = 'Frequency Smoothing';
            app.UIAxesFreqSmooth = uiaxes(app.FrequencySmoothingTab);
            title(app.UIAxesFreqSmooth, 'Filtering Result')
            xlabel(app.UIAxesFreqSmooth, '')
            ylabel(app.UIAxesFreqSmooth, '')
            app.ChooseFilterDropDownLabel = uilabel(app.FrequencySmoothingTab);
            app.ChooseFilterDropDownLabel.Text = 'Choose Filter';
            app.ChooseFilterDropDown = uidropdown(app.FrequencySmoothingTab);
            app.ChooseFilterDropDown.Items = {'ILPF', 'GLPF', 'BLPF', 'IHPF', 'GHPF', 'BHPF'};
            app.ChooseFilterDropDown.Value = 'ILPF';
            app.FilteringwithLowPassandHighPassFiltersLabel = uilabel(app.FrequencySmoothingTab);
            app.FilteringwithLowPassandHighPassFiltersLabel.Text = 'Filtering with Low Pass and High Pass Filters';
            app.ChooseFileButton_MeanFilter_2 = uibutton(app.FrequencySmoothingTab, 'push');
            app.ChooseFileButton_MeanFilter_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileFreqSmooth, true);
            app.ChooseFileButton_MeanFilter_2.Text = 'Choose File';
            app.SelectthefilehereEditField_2Label_2 = uilabel(app.FrequencySmoothingTab);
            app.SelectthefilehereEditField_2Label_2.Text = 'Select image file here: ';
            app.SelectthefilehereEditField_Conv_2 = uieditfield(app.FrequencySmoothingTab, 'text');
            app.ApplyButton = uibutton(app.FrequencySmoothingTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFreqSmoothButton, true);
            app.ApplyButton.Text = 'Apply';
            app.EnterD0valueLabel = uilabel(app.FrequencySmoothingTab);
            app.EnterD0valueLabel.Text = 'Enter D0 value';
            app.D0 = uieditfield(app.FrequencySmoothingTab, 'numeric');
            app.D0.ValueChangedFcn = createCallbackFcn(app, @D0ValueChanged, true);
            app.D0.Value = 50;
            app.HighBoostingTab = uitab(app.TabGroup);
            app.HighBoostingTab.Title = 'High Boosting';
            app.GridLayout4 = uigridlayout(app.HighBoostingTab);
            app.SourceImageButton = uibutton(app.GridLayout4, 'push');
            app.SourceImageButton.ButtonPushedFcn = createCallbackFcn(app, @SourceImageButtonPushed, true);
            app.SourceImageButton.Text = 'Choose Image';
            app.HighBoostButton = uibutton(app.GridLayout4, 'push');
            app.HighBoostButton.ButtonPushedFcn = createCallbackFcn(app, @HighBoostButtonPushed, true);
            app.HighBoostButton.Text = 'Apply Highboost coefficient';
            app.SourceEditField = uieditfield(app.GridLayout4, 'text');
            app.SourceImage = uiimage(app.GridLayout4);
            app.HighBoostImage = uiimage(app.GridLayout4);
            app.HighBoostEditField = uieditfield(app.GridLayout4, 'numeric');
            app.HighBoostEditField.Value = 2.5;
            app.LowPassImage = uiimage(app.GridLayout4);
            app.MedianFilteringTab = uitab(app.TabGroup);
            app.MedianFilteringTab.Title = 'Median Filtering';
            app.GridLayout5 = uigridlayout(app.MedianFilteringTab);
            app.ClearImageButton = uibutton(app.GridLayout5, 'push');
            app.ClearImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearImageButtonPushed, true);
            app.ClearImageButton.Text = 'Choose Image Source';
            app.ClearEditField = uieditfield(app.GridLayout5, 'text');
            app.ClearImage = uiimage(app.GridLayout5);
            app.NoiseImage = uiimage(app.GridLayout5);
            app.MedianImage = uiimage(app.GridLayout5);
            app.NoiseEditField = uieditfield(app.GridLayout5, 'text');
            app.SaltPepperEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SaltPepperEditField.Value = 0.05;
            app.NoiseImageButton = uibutton(app.GridLayout5, 'push');
            app.NoiseImageButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseImageButtonPushed, true);
            app.NoiseImageButton.Text = 'Choose Image Noise';
            app.SaltPepperButton = uibutton(app.GridLayout5, 'push');
            app.SaltPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltPepperButtonPushed, true);
            app.SaltPepperButton.Text = 'or Apply Salt Pepper from Source';
            app.ResultImageButton = uibutton(app.GridLayout5, 'push');
            app.ResultImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResultImageButtonPushed, true);
            app.ResultImageButton.Text = 'Filter the Image Noise!';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end